classdef face_detector_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        UIAxes             matlab.ui.control.UIAxes
        UIAxes_2           matlab.ui.control.UIAxes
        UIAxes_3           matlab.ui.control.UIAxes
        FaceDetectorLabel  matlab.ui.control.Label
        upload             matlab.ui.control.Button
        start              matlab.ui.control.Button
        Label              matlab.ui.control.Label
        Label_2            matlab.ui.control.Label
        Label_3            matlab.ui.control.Label
        clear              matlab.ui.control.Button
        Image              matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global state
            state = 0; % 初期状態 
        end

        % Button pushed function: start
        function detectButtonPushed(app, event)
            global I path state
            if state == 1
                I = imread(join([path I]));
                % グレースケールに変える  
                G = rgb2gray(I);  
                
                % YCbCr色空間にする  
                YCbCr = rgb2ycbcr(I); 
                % HSV=rgb2hsv(I);
                
                % 画像のサイズを読む  
                heigth = size(G,1);  
                width = size(G,2);  
                
                % 肌色モデルにより画像を二値化する　根据肤色模型将图像二值化  
                for i = 1:heigth  
                    for j = 1:width  
                        Y = YCbCr(i,j,1);  
                        Cb = YCbCr(i,j,2);  
                        Cr = YCbCr(i,j,3);  
                        if(Y < 80)  
                            G(i,j) = 0;  
                        else  
                            if(skin(Y,Cb,Cr) == 1)  
                                G(i,j) = 255;  
                            else  
                                G(i,j) = 0;  
                            end  
                        end  
                    end  
                end  
                
                
                % 形態学処理   
                %SE=strel('arbitrary',eye(5));  
                SE = strel('arbitrary',eye(5));
                % モルフォロジー"構造化要素" 
                % imopenイメージのモルフォロジー オープニング
                G = imopen(G,SE);  
                % imclose先膨胀再腐蚀  
                % G = imclose(gray,SE);  
             
                % 小さいバイナリオブジェクトを除去
                G=bwareaopen(G,200);
          
                
                % バイナリオブジェクト（白い部分）をすべて取り出す
                [L,num] = bwlabel(G,8);  
                STATS = regionprops(L,'area','boundingbox');
                % 最大連結域
                Ar = cat(1, STATS.Area);
                ind = find(Ar ==max(Ar)); % 最大連結域のインデックス
                G(find(L~=ind))=0; % 最大連結域以外のところを黒にする
             
                imshow(G,'Parent',app.UIAxes_2);  
                imshow(I,'Parent',app.UIAxes_3);hold on;
                % 最大連結域の座標情報
                box = STATS(ind).BoundingBox;  
                x = box(1);    % x座標  
                y = box(2);    % y座標 
                w = box(3);    % 幅  
                h = box(4);    % 高さ  
                
                % 縦横比
                ratio = h/w; 
                x=floor(x); %X の各要素をその要素以下の最も近い整数に丸める
                y=floor(y);
                
                % 検出範囲は画像内に抑制する
                if x==0
                    x = x+1;
                end
                if y==0
                    y=y+1;
                end
                if w >= width
                    w = width-1;
                end
                if h >= heigth
                    h = heigth-1;
                end
                
                rectangle('Position',[x,y,w,h],'EdgeColor','b','Parent',app.UIAxes_3);
                hold on;
                
                % 以下の条件を満たす：  
                %   1.縦横比は（0.5,2）以内
                %   2.findeye == 1   
                if ratio < 2.0 && ratio > 0.5  && findeye(G,x,y,w,h) == 1  
                % 人の顔に当たる範囲を記録 
      
                    if ratio > 1.6
                        h=0.75*h;
                    elseif ratio < 1.0
                        w=0.80*w;
                    end
                    rectangle('Position',[x,y,w,h],'EdgeColor','r','Parent',app.UIAxes_3); hold off;                   
                end 
            end
            state = 0; % 処理後状態を0に戻す
        end

        % Button pushed function: upload
        function uploadPushed(app, event)
            global I path state
            [I,path] = uigetfile({'*.png; *.jpg; *.jpeg;','ビデオファイル'},'MultiSelect','off');
            if I ~= 0
                imshow(I,'Parent',app.UIAxes);
                state = 1;
            end
        end

        % Button pushed function: clear
        function clearButtonPushed(app, event)
            global state
            state = 0;
            cla(app.UIAxes,"reset");
            cla(app.UIAxes_2,"reset");
            cla(app.UIAxes_3,"reset");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.GridLayout);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_3 = uiaxes(app.GridLayout);
            title(app.UIAxes_3, '')
            xlabel(app.UIAxes_3, '')
            ylabel(app.UIAxes_3, '')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.FaceDetectorLabel = uilabel(app.GridLayout);
            app.FaceDetectorLabel.Text = ' Face Detector';
            app.upload = uibutton(app.GridLayout, 'push');
            app.upload.ButtonPushedFcn = createCallbackFcn(app, @uploadPushed, true);
            app.upload.Text = '画像を開く...';
            app.start = uibutton(app.GridLayout, 'push');
            app.start.ButtonPushedFcn = createCallbackFcn(app, @detectButtonPushed, true);
            app.start.Text = ' 実行';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '元の画像';
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_2.Text = {'二値化画像'; ''};
            app.Label_3 = uilabel(app.GridLayout);
            app.Label_3.Text = '識別結果';
            app.clear = uibutton(app.GridLayout, 'push');
            app.clear.ButtonPushedFcn = createCallbackFcn(app, @clearButtonPushed, true);
            app.clear.Text = 'クリア';
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = 'パンダの顔アイコン3.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = face_detector_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end